classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TextRecogniser           matlab.ui.Figure
        WczytajobrazButton       matlab.ui.control.Button
        RozpoznajtekstButton     matlab.ui.control.Button
        EditFieldLabel           matlab.ui.control.Label
        EditField                matlab.ui.control.EditField
        BinaryzacjaButton        matlab.ui.control.Button
        KolorowanskoButton       matlab.ui.control.Button
        TrenujsieneuronowButton  matlab.ui.control.Button
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        obrazekd;
        binarny;
        fun =  {@AO5RBlairBliss, @AO5RDanielsson, @AO5RFeret, @AO5RHaralick, @AO5RMalinowska, @AO5RCircularityL, @AO5RCircularityS, @AO5RShape};
        wsp_train = {@wspolczynniki_train};
        obrazek;
         dict = ['A';'B'; 'C'; 'D'; 'E'; 'F'; 'G'; 'H'; 'I'; 'J'; 'K'; 'L'; 'M'; 'N'; 'O'; 'P'; 'Q'; 'R'; 'S'; 'T'; 'U'; 'V'; 'W'; 'X'; 'Y'; 'Z';'1';'2';'3';'4';'5';'6';'7';'8';'9';'0'];
        nn = load ('ocr_final_8_new.mat'); 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: WczytajobrazButton
        function WczytajobrazButtonPushed(app, event)
            [file, path] = uigetfile({'*.png';'*.jpg';'*.bmp';'*.svg';'*.gif'}, 'Wybierz obrazek:');
       %  figure(app.TextRecogniser);
            filepath=[path file];
            if isequal(file,0)
               tts('Nie wczytałeś żadnego obrazka!');
            else
               app.obrazekd = double(imread(filepath))/255;
               app.obrazek = imread(filepath);
               
               imshow(app.obrazekd,'parent',app.UIAxes);
               tts('Wczytałeś obraz!');
               app.EditField.Value = '';
            end

        end

        % Button pushed function: RozpoznajtekstButton
        function RozpoznajtekstButtonPushed(app, event)
            app.binarny = rgb2gray(app.obrazekd);
            if round(mean(app.binarny(:)))==1
                app.binarny = ~imbinarize(app.binarny);
            else
                app.binarny = imbinarize(app.binarny);
            end

            app.binarny= imclose(app.binarny,ones(3));
            copia = app.binarny;
            obiekty = regionprops(copia,'Image');
            all = regionprops(copia,'all');
            
            % Get bounding boxes for all the regions
            bboxes = vertcat(all.BoundingBox);

            % Convert from the [x y width height] bounding box format to the [xmin ymin
            % xmax ymax] format for convenience.
            xmin = bboxes(:,1);
            ymin = bboxes(:,2);
            xmax = xmin + bboxes(:,3) - 1;
            ymax = ymin + bboxes(:,4) - 1;
            
            % Expand the bounding boxes by a small amount.
            expansionAmount = 0.02;
            xmin = (1-expansionAmount) * xmin;
            ymin = (1-expansionAmount) * ymin;
            xmax = (1+expansionAmount) * xmax;
            ymax = (1+expansionAmount) * ymax;
            
            % Clip the bounding boxes to be within the image bounds
            xmin = max(xmin, 1);
            ymin = max(ymin, 1);
            xmax = min(xmax, size(rgb2gray(app.obrazekd),2));
            ymax = min(ymax, size(rgb2gray(app.obrazekd),1));
            
            % Show the expanded bounding boxes
            expandedBBoxes = [xmin ymin xmax-xmin+1 ymax-ymin+1];
            IExpandedBBoxes = insertShape(app.obrazek,'Rectangle',expandedBBoxes,'LineWidth',3);
            
            
            imshow(IExpandedBBoxes,'parent',app.UIAxes);


            macierz = zeros(length(obiekty), length(app.fun));
            
            for i = 1:length(obiekty)
                for j = 1:length(app.fun)
                    copia = obiekty(i).Image;
                    copia = padarray(copia,0);
                    copia = imresize(copia, [300, 300]);
                    macierz(i,j) =  app.fun{j}(copia);
                end
            end
            out1 = app.nn.nn(macierz');
            result = '';
            for i = 1:1:size(out1,2)
                result = strcat(result,string(app.dict(find(out1(:,i) == max(out1(:,i))))));
            end
            app.EditField.Value = result;
            tts(char(result));
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            
        end

        % Value changing function: EditField
        function EditFieldValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Button pushed function: BinaryzacjaButton
        function BinaryzacjaButtonPushed(app, event)
               app.binarny = rgb2gray(app.obrazekd);
               app.binarny = ~imbinarize(app.binarny);
               app.binarny= imclose(app.binarny,ones(3));
               imshow(app.binarny,'parent',app.UIAxes);
               tts('Obraz został zbinaryzowany');
               
        end

        % Button pushed function: KolorowanskoButton
        function KolorowanskoButtonPushed(app, event)
               app.binarny = rgb2gray(app.obrazekd);
               app.binarny = ~imbinarize(app.binarny);
               app.binarny= imclose(app.binarny,ones(3));
               kolor=bwlabel(app.binarny);
               
               imshow(label2rgb(kolor),'parent',app.UIAxes);
        end

        % Button pushed function: TrenujsieneuronowButton
        function TrenujsieneuronowButtonPushed(app, event)
            app.wsp_train{1}();
            app.EditField.Value = '';
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TextRecogniser = uifigure('Visible', 'off');
            app.TextRecogniser.Name = 'MATLAB App';
            app.WczytajobrazButton = uibutton(app.TextRecogniser, 'push');
            app.WczytajobrazButton.ButtonPushedFcn = createCallbackFcn(app, @WczytajobrazButtonPushed, true);
            app.WczytajobrazButton.Text = 'Wczytaj obraz';
            app.RozpoznajtekstButton = uibutton(app.TextRecogniser, 'push');
            app.RozpoznajtekstButton.ButtonPushedFcn = createCallbackFcn(app, @RozpoznajtekstButtonPushed, true);
            app.RozpoznajtekstButton.Text = 'Rozpoznaj tekst';
            app.EditFieldLabel = uilabel(app.TextRecogniser);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.TextRecogniser, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.ValueChangingFcn = createCallbackFcn(app, @EditFieldValueChanging, true);
            app.BinaryzacjaButton = uibutton(app.TextRecogniser, 'push');
            app.BinaryzacjaButton.ButtonPushedFcn = createCallbackFcn(app, @BinaryzacjaButtonPushed, true);
            app.BinaryzacjaButton.Text = 'Binaryzacja';
            app.KolorowanskoButton = uibutton(app.TextRecogniser, 'push');
            app.KolorowanskoButton.ButtonPushedFcn = createCallbackFcn(app, @KolorowanskoButtonPushed, true);
            app.KolorowanskoButton.Text = 'Kolorowansko';
            app.TrenujsieneuronowButton = uibutton(app.TextRecogniser, 'push');
            app.TrenujsieneuronowButton.ButtonPushedFcn = createCallbackFcn(app, @TrenujsieneuronowButtonPushed, true);
            app.TrenujsieneuronowButton.Text = 'Trenuj sieć neuronową';
            app.UIAxes = uiaxes(app.TextRecogniser);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            % Show the figure after all components are created
            app.TextRecogniser.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TextRecogniser)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TextRecogniser)
        end
    end
end